classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SEGMENTATIONPanel            matlab.ui.container.Panel
        DetailsDropDown_2            matlab.ui.control.DropDown
        IMAGEDETAILSPanel            matlab.ui.container.Panel
        DetailsDropDown              matlab.ui.control.DropDown
        RESETTOORIGINALButton        matlab.ui.control.Button
        SELECTIMAGEButtonGroup       matlab.ui.container.ButtonGroup
        Image1Button                 matlab.ui.control.ToggleButton
        Image3Button                 matlab.ui.control.ToggleButton
        Image2Button                 matlab.ui.control.ToggleButton
        TabGroup                     matlab.ui.container.TabGroup
        NOISESTab                    matlab.ui.container.Tab
        PoissonButton                matlab.ui.control.Button
        SpeckleButton                matlab.ui.control.Button
        GaussianButton               matlab.ui.control.Button
        SaltPepperButton             matlab.ui.control.Button
        FILTERSTab                   matlab.ui.container.Tab
        WaveletButton                matlab.ui.control.Button
        WienerButton                 matlab.ui.control.Button
        GaussianButton_2             matlab.ui.control.Button
        MedianButton                 matlab.ui.control.Button
        ROTATECLOCKWISESpinner       matlab.ui.control.Spinner
        ROTATECLOCKWISESpinnerLabel  matlab.ui.control.Label
        ListBox                      matlab.ui.control.ListBox
        IMAGETYPEButtonGroup         matlab.ui.container.ButtonGroup
        YCbCrButton                  matlab.ui.control.ToggleButton
        HSVButton                    matlab.ui.control.ToggleButton
        BinaryButton                 matlab.ui.control.ToggleButton
        GrayscaleButton              matlab.ui.control.ToggleButton
        CHANNELSPanel                matlab.ui.container.Panel
        GREENButton                  matlab.ui.control.Button
        BLUEButton                   matlab.ui.control.Button
        REDButton                    matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

     properties (Access = private)
        OriginalImage
        ProcessedImage
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: SELECTIMAGEButtonGroup
        function SELECTIMAGEButtonGroupSelectionChanged(app, event)
            selectedButton = app.SELECTIMAGEButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Image 1'
                    img = imread('img1.jpg');
                case 'Image 2'
                    img = imread('img2.jpg');
                case 'Image 3'
                    img = imread('img3.jpg');
                otherwise
                    return;
            end
            app.OriginalImage = img;
            imshow(img, 'parent' ,app.UIAxes);  
            title(app.UIAxes, ['Loaded: ', selectedButton.Text]);

        end

        % Selection changed function: IMAGETYPEButtonGroup
        function IMAGETYPEButtonGroupSelectionChanged(app, event)
             if isempty(app.OriginalImage)
                 uialert(app.UIFigure, 'Please load an image first.', 'No Image');
                 return;
             end
             selected = app.IMAGETYPEButtonGroup.SelectedObject.Text;
             img = app.OriginalImage;
             
             switch selected
                 case 'Grayscale'
                     if size(img,3)==3, img = rgb2gray(img);end
                 case 'Binary'
                     if size(img,3)==3, img = rgb2gray(img); end
                     img = imbinarize(img);
                 case 'HSV'
                     if size(img,3)==3, img = rgb2hsv(img);
                     else, showError('HSV needs RGB'); return;
                     end
                 case 'YCbCr'
                     if size(img,3)==3, img = rgb2ycbcr(img);
                     else, showError('YCbCr needs RGB'); return;
                     end
             end
             
             imshow(img, 'Parent', app.UIAxes);
             title(app.UIAxes, ['Image Type: ' selected]);
            function showError(msg)
                 uialert(app.UIFigure, msg, 'Error');
            end

        end

        % Button pushed function: REDButton
        function REDButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an image first.','No Image');
                return;
            end

            img = app.OriginalImage;

            if size(img,3) ~= 3
                uialert(app.UIFigure,' Red channel only works for RGB images.','Error');
                return;
            end

            redChannel = img(:,:,1);
            blank = zeros(size(redChannel),'like',redChannel);

            redImage = cat(3,redChannel,blank,blank);

            imshow(redImage , 'Parent', app.UIAxes);
            title(app.UIAxes, 'Red Channel');
        end

        % Button pushed function: BLUEButton
        function BLUEButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an image first.','No Image');
                return;
            end

            img = app.OriginalImage;

            if size(img,3) ~= 3
                uialert(app.UIFigure,' Blue channel only works for RGB images.','Error');
                return;
            end

           blueChannel = img(:,:,3);
            blank = zeros(size(blueChannel),'like',blueChannel);

            blueImage = cat(3,blank,blank,blueChannel);

            imshow(blueImage , 'Parent', app.UIAxes);
            title(app.UIAxes, 'Blue Channel');
        end

        % Button pushed function: GREENButton
        function GREENButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Please load an image first.','No Image');
                return;
            end

            img = app.OriginalImage;

            if size(img,3) ~= 3
                uialert(app.UIFigure,' Green channel only works for RGB images.','Error');
                return;
            end

           greenChannel = img(:,:,3);
            blank = zeros(size(greenChannel),'like',greenChannel);

            greenImage = cat(3,blank,greenChannel,blank);

            imshow(greenImage , 'Parent', app.UIAxes);
            title(app.UIAxes, 'Green Channel');
        end

        % Button pushed function: SaltPepperButton
        function SaltPepperButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Load an image first.','No image'); return;
            end
            img = im2double(app.OriginalImage);
            noisyimg = imnoise(img,'gaussian',0.01);
            imshow(noisyimg, 'Parent',app.UIAxes);
            title(app.UIAxes ,'Gaussian Noise');
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Load an image first.','No image'); return;
            end
            img = im2double(app.OriginalImage);
            noisyimg = imnoise(img,'salt & pepper',0.02);
            imshow(noisyimg, 'Parent',app.UIAxes);
            title(app.UIAxes ,'salt & pepper Noise');
        end

        % Button pushed function: SpeckleButton
        function SpeckleButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Load an image first.','No image'); return;
            end
            img = im2double(app.OriginalImage);
            noisyimg = imnoise(img,'speckle');
            imshow(noisyimg, 'Parent',app.UIAxes);
            title(app.UIAxes ,'Speckle Noise');
        end

        % Button pushed function: PoissonButton
        function PoissonButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Load an image first.','No image'); return;
            end
            img = im2double(app.OriginalImage);
            noisyimg = imnoise(img,'poisson');
            imshow(noisyimg, 'Parent',app.UIAxes);
            title(app.UIAxes ,'Poisson Noise');
        end

        % Button pushed function: MedianButton
        function MedianButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Load an image first.', 'No Image'); return;
            end
            img = app.OriginalImage;
            if size(img,3) == 3, img = rgb2gray(img); end
            filteredImg = medfilt2(img, [3 3]);
            imshow(filteredImg, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Median Filter');
        end

        % Button pushed function: GaussianButton_2
        function GaussianButton_2Pushed(app, event)
                if isempty(app.OriginalImage)
                    uialert(app.UIFigure, 'Load an image first.', 'No Image'); return;
                end
                img = im2double(app.OriginalImage);
                filteredImg = imgaussfilt(img, 1); 
                imshow(filteredImg, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Gaussian Filter');
        end

        % Button pushed function: WienerButton
        function WienerButtonPushed(app, event)
                if isempty(app.OriginalImage)
                    uialert(app.UIFigure, 'Load an image first.', 'No Image'); return;
                end
                img = app.OriginalImage;
                if size(img,3) == 3, img = rgb2gray(img); end
                filteredImg = wiener2(img, [5 5]);
                imshow(filteredImg, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Wiener Filter');
        end

        % Button pushed function: WaveletButton
        function WaveletButtonPushed(app, event)
                if isempty(app.OriginalImage)
                    uialert(app.UIFigure, 'Load an image first.', 'No Image'); return;
                end
                img = app.OriginalImage;
                if size(img,3) == 3, img = rgb2gray(img); end
                img = im2double(img);
                denoisedImg = wdenoise2(img);
                imshow(denoisedImg, 'Parent', app.UIAxes);
                title(app.UIAxes, 'Wavelet Denoising');
        end

        % Value changed function: ROTATECLOCKWISESpinner
        function ROTATECLOCKWISESpinnerValueChanged(app, event)
            angle = app.ROTATECLOCKWISESpinner.Value;  % Get angle from spinner
    if isempty(app.OriginalImage)
        uialert(app.UIFigure, 'Please load an image first.', 'No Image');
        return;
    end

    % Rotate image clockwise (note: imrotate rotates counter-clockwise by default)
    rotatedImg = imrotate(app.OriginalImage, -angle, 'bilinear', 'crop');

    imshow(rotatedImg, 'Parent', app.UIAxes);
    title(app.UIAxes, ['Rotated: ' num2str(angle) '° Clockwise']);
            
        end

        % Button pushed function: RESETTOORIGINALButton
        function RESETTOORIGINALButtonPushed(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure, 'Load an image first.', 'No Image');
                return;
            end
    imshow(app.OriginalImage, 'Parent', app.UIAxes);
    title(app.UIAxes, 'Original Image');
        end

        % Value changed function: DetailsDropDown_2
        function DetailsDropDown_2ValueChanged(app, event)
            if isempty(app.OriginalImage)
                uialert(app.UIFigure,'Please load an image first.', 'No image');
                return;
            end
            
            segmentation = app.DetailsDropDown_2.Value;
            img = app.OriginalImage;

            switch segmentation
                case 'Select'
                    return;

                case 'Thresholding'
                    if size(img,3)== 3
                        img = rgb2gray(img);
                    end
                    bw = imbinarize(img);
                    imshow(bw,'Parent',app.UIAxes)
                    title(app.UIAxes, 'Thresholding');

                case 'Colour-Based'
                    hsvImg = rgb2hsv(img);
                    h = hsvImg(:,:,1);
                    mask = (h>0.2 & h<0.5);
                    imshow(mask, 'Parent', app.UIAxes);
                    title(app.UIAxes, 'Colour-Based');

                case 'Edge-Based'
                    if size(img,3)==3
                        gray = rgb2gray(img);
                    else 
                        gray = img;
                    end

                    edges = edge(gray, 'canny');
                    imshow(edges,'Parent', app.UIAxes);
                    title(app.UIAxes,'Edge-Based');
            end 
        end

        % Value changed function: DetailsDropDown
        function DetailsDropDownValueChanged(app, event)
            
    if isempty(app.OriginalImage)
        uialert(app.UIFigure, 'Please load an image first.', 'No Image');
        return;
    end

    selectedDetail = app.DetailsDropDown.Value;
    img = app.OriginalImage;

    % Convert to grayscale if needed
    if size(img, 3) == 3
        gray = rgb2gray(img);
    else
        gray = img;
    end

    switch selectedDetail
        case 'Select'
            return; % Do nothing

        case 'Sobel Edge'
            edges = edge(gray, 'sobel');
            imshow(edges, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Sobel Edge Detection');

        case 'Canny Edge'
            edges = edge(gray, 'canny');
            imshow(edges, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Canny Edge Detection');

        case 'Corner Detection'
            if size(img,3) == 3
                gray = rgb2gray(img);
            else 
                gray = img;
            end


            if ~isa(gray,'uint8')
                gray = im2uint8(gray);
            end
            try
                corners = detectHarrisFeatures(gray);
                imshow(gray, 'Parent', app.UIAxes);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, corners.selectStrongest(50), 'r+');
                hold(app.UIAxes, 'off');
                title(app.UIAxes, 'Corner Detection');
            catch 
                uialert(app.UIFigure,...
                    'detectHarrisFeatures not available. try using cornometric instead.',...
                    'Feature not Found');
            end     
    end
            
        end

        % Callback function: not associated with a component
        function RestartButtonPushed(app, event)
     if ~isempty(app.OriginalImage)
        app.ProcessedImage = app.OriginalImage;  % if using another field
        imshow(app.OriginalImage, 'Parent', app.UIAxes);
        title(app.UIAxes, 'Original Image');
    else
        cla(app.UIAxes);  % clear axes
        title(app.UIAxes, '');
    end

    % 2. Reset dropdowns
    app.ImageTypeDropDown.Value = 'Select';  % if this is your default
    app.ImageDetailsDropDown.Value = 'Select';
    app.SegmentationDropDown.Value = 'Select';

    % 3. Reset spinners (e.g., for rotation)
    if isprop(app, 'RotationSpinner')
        app.RotationSpinner.Value = 0;
    end

    % 4. Reset toggle buttons (image selection or other groups)
    if isprop(app, 'ImageToggleButtonGroup')
        app.ImageToggleButtonGroup.SelectedObject = [];
    end

    % 5. Clear any flags or internal variables (if used)
    % e.g., app.CurrentImageType = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.CHANNELSPanel = uipanel(app.UIFigure);
            app.CHANNELSPanel.Title = 'CHANNELS :';
            app.REDButton = uibutton(app.CHANNELSPanel, 'push');
            app.REDButton.ButtonPushedFcn = createCallbackFcn(app, @REDButtonPushed, true);
            app.REDButton.Text = 'RED';
            app.BLUEButton = uibutton(app.CHANNELSPanel, 'push');
            app.BLUEButton.ButtonPushedFcn = createCallbackFcn(app, @BLUEButtonPushed, true);
            app.BLUEButton.Text = 'BLUE';
            app.GREENButton = uibutton(app.CHANNELSPanel, 'push');
            app.GREENButton.ButtonPushedFcn = createCallbackFcn(app, @GREENButtonPushed, true);
            app.GREENButton.Text = 'GREEN';
            app.IMAGETYPEButtonGroup = uibuttongroup(app.UIFigure);
            app.IMAGETYPEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IMAGETYPEButtonGroupSelectionChanged, true);
            app.IMAGETYPEButtonGroup.Title = 'IMAGE TYPE :';
            app.GrayscaleButton = uitogglebutton(app.IMAGETYPEButtonGroup);
            app.GrayscaleButton.Text = 'Grayscale';
            app.GrayscaleButton.Value = true;
            app.BinaryButton = uitogglebutton(app.IMAGETYPEButtonGroup);
            app.BinaryButton.Text = 'Binary';
            app.HSVButton = uitogglebutton(app.IMAGETYPEButtonGroup);
            app.HSVButton.Text = 'HSV';
            app.YCbCrButton = uitogglebutton(app.IMAGETYPEButtonGroup);
            app.YCbCrButton.Text = 'YCbCr';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {''};
            app.ListBox.Value = {};
            app.ROTATECLOCKWISESpinnerLabel = uilabel(app.UIFigure);
            app.ROTATECLOCKWISESpinnerLabel.Text = {'ROTATE'; 'CLOCKWISE'};
            app.ROTATECLOCKWISESpinner = uispinner(app.UIFigure);
            app.ROTATECLOCKWISESpinner.Limits = [0 360];
            app.ROTATECLOCKWISESpinner.RoundFractionalValues = 'on';
            app.ROTATECLOCKWISESpinner.ValueChangedFcn = createCallbackFcn(app, @ROTATECLOCKWISESpinnerValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NOISESTab = uitab(app.TabGroup);
            app.NOISESTab.Title = 'NOISES';
            app.SaltPepperButton = uibutton(app.NOISESTab, 'push');
            app.SaltPepperButton.ButtonPushedFcn = createCallbackFcn(app, @SaltPepperButtonPushed, true);
            app.SaltPepperButton.Text = 'Salt & Pepper';
            app.GaussianButton = uibutton(app.NOISESTab, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.SpeckleButton = uibutton(app.NOISESTab, 'push');
            app.SpeckleButton.ButtonPushedFcn = createCallbackFcn(app, @SpeckleButtonPushed, true);
            app.SpeckleButton.Text = 'Speckle';
            app.PoissonButton = uibutton(app.NOISESTab, 'push');
            app.PoissonButton.ButtonPushedFcn = createCallbackFcn(app, @PoissonButtonPushed, true);
            app.PoissonButton.Text = 'Poisson';
            app.FILTERSTab = uitab(app.TabGroup);
            app.FILTERSTab.Title = 'FILTERS';
            app.MedianButton = uibutton(app.FILTERSTab, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @MedianButtonPushed, true);
            app.MedianButton.Text = 'Median';
            app.GaussianButton_2 = uibutton(app.FILTERSTab, 'push');
            app.GaussianButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaussianButton_2Pushed, true);
            app.GaussianButton_2.Text = 'Gaussian';
            app.WienerButton = uibutton(app.FILTERSTab, 'push');
            app.WienerButton.ButtonPushedFcn = createCallbackFcn(app, @WienerButtonPushed, true);
            app.WienerButton.Text = 'Wiener';
            app.WaveletButton = uibutton(app.FILTERSTab, 'push');
            app.WaveletButton.ButtonPushedFcn = createCallbackFcn(app, @WaveletButtonPushed, true);
            app.WaveletButton.Text = 'Wavelet';
            app.SELECTIMAGEButtonGroup = uibuttongroup(app.UIFigure);
            app.SELECTIMAGEButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SELECTIMAGEButtonGroupSelectionChanged, true);
            app.SELECTIMAGEButtonGroup.Title = 'SELECT IMAGE';
            app.Image2Button = uitogglebutton(app.SELECTIMAGEButtonGroup);
            app.Image2Button.Text = 'Image 2';
            app.Image2Button.Value = true;
            app.Image3Button = uitogglebutton(app.SELECTIMAGEButtonGroup);
            app.Image3Button.Text = 'Image 3';
            app.Image1Button = uitogglebutton(app.SELECTIMAGEButtonGroup);
            app.Image1Button.Text = 'Image 1';
            app.RESETTOORIGINALButton = uibutton(app.UIFigure, 'push');
            app.RESETTOORIGINALButton.ButtonPushedFcn = createCallbackFcn(app, @RESETTOORIGINALButtonPushed, true);
            app.RESETTOORIGINALButton.Text = 'RESET TO ORIGINAL';
            app.IMAGEDETAILSPanel = uipanel(app.UIFigure);
            app.IMAGEDETAILSPanel.Title = 'IMAGE DETAILS';
            app.DetailsDropDown = uidropdown(app.IMAGEDETAILSPanel);
            app.DetailsDropDown.Items = {'select', 'Sobel Edge', 'Canny Edge', 'Corner Detection'};
            app.DetailsDropDown.ValueChangedFcn = createCallbackFcn(app, @DetailsDropDownValueChanged, true);
            app.DetailsDropDown.Value = 'select';
            app.SEGMENTATIONPanel = uipanel(app.UIFigure);
            app.SEGMENTATIONPanel.Title = 'SEGMENTATION';
            app.DetailsDropDown_2 = uidropdown(app.SEGMENTATIONPanel);
            app.DetailsDropDown_2.Items = {'select', 'Thresholding', 'Colour-Based', 'Edge-Based'};
            app.DetailsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @DetailsDropDown_2ValueChanged, true);
            app.DetailsDropDown_2.Value = 'select';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end