classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ShowAllLabelsSpinner        matlab.ui.control.Spinner
        TextArea                    matlab.ui.control.TextArea
        RESETButton                 matlab.ui.control.Button
        ShowAllLabelsSpinnerLabel   matlab.ui.control.Label
        BrowseImagesPanel           matlab.ui.container.Panel
        PresstoLoadButton           matlab.ui.control.Button
        ProcessingWorkflowPanel     matlab.ui.container.Panel
        Step2EdgeDetectionButton    matlab.ui.control.Button
        NONEButton                  matlab.ui.control.Button
        Step4LabelcomponentsButton  matlab.ui.control.Button
        Step3SmoothingButton        matlab.ui.control.Button
        Step1ApplyingMedianFilterButton  matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

properties (Access = public)
    OriginalImage
    MedianFilteredImage
    EdgeImage
    SmoothedImage
    ConnectedComponents
    NumComponents
    allComponents   % Cell array to store individual components
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Disable all processing buttons
    app.Step1ApplyingMedianFilterButton.Enable = 'off';
    app.Step2EdgeDetectionButton.Enable = 'off';
    app.Step3SmoothingButton.Enable = 'off';
    app.Step4LabelcomponentsButton.Enable = 'off';
        end

        % Button pushed function: PresstoLoadButton
        function PresstoLoadButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp'}, 'Select an image');
            if isequal(file,0)
                uialert(app.UIFigure, 'No file selected.', 'Load Error');
                return;
            end
            app.OriginalImage = imread(fullfile(path, file));
            imshow(app.OriginalImage, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Original Image');
            app.UIAxes.Title.Color = [0.35 0.45 0.40];app.UIAxes.Title.FontSize = 14;
            app.UIAxes.Title.FontWeight = 'bold';
            app.Step1ApplyingMedianFilterButton.Enable = 'on';
        end

        % Button pushed function: Step1ApplyingMedianFilterButton
        function Step1ApplyingMedianFilterButtonPushed(app, event)
            if isempty(app.OriginalImage)
        uialert(app.UIFigure, 'Please load an image first.', 'Missing Image');
        return;
           end
    grayImage = rgb2gray(app.OriginalImage);
    app.MedianFilteredImage = medfilt2(grayImage, [3 3]);

    imshow(app.MedianFilteredImage, 'Parent', app.UIAxes);
    title(app.UIAxes, 'After Median Filtering');
    app.UIAxes.Title.Color = [0.35 0.45 0.40];
    app.UIAxes.Title.FontSize = 14;
    app.UIAxes.Title.FontWeight = 'bold';

    app.Step2EdgeDetectionButton.Enable = 'on';
        end

        % Button pushed function: Step2EdgeDetectionButton
        function Step2EdgeDetectionButtonPushed(app, event)

    if isempty(app.MedianFilteredImage)
        uialert(app.UIFigure, 'Apply median filter first.', 'Missing Step');
        return;
    end
    app.EdgeImage = edge(app.MedianFilteredImage, 'sobel');

    imshow(app.EdgeImage, 'Parent', app.UIAxes);
    title(app.UIAxes, 'Edge Detection');
    app.UIAxes.Title.Color = [0.35 0.45 0.40];
    app.UIAxes.Title.FontSize = 14;
    app.UIAxes.Title.FontWeight = 'bold';

    app.Step3SmoothingButton.Enable = 'on';

        end

        % Button pushed function: Step3SmoothingButton
        function Step3SmoothingButtonPushed(app, event)
    if isempty(app.EdgeImage)
        uialert(app.UIFigure, 'Please perform edge detection first.', 'Missing Step');
        return;
    end

    % Smoothing via convolution
    msk = [0 0 0 0 0;
           0 1 1 1 0;
           0 1 1 1 0;
           0 1 1 1 0;
           0 0 0 0 0];
       
    smoothedImage = conv2(double(app.EdgeImage), double(msk), 'same');
    app.SmoothedImage = smoothedImage > 0;

    imshow(app.SmoothedImage, 'Parent', app.UIAxes);
    title(app.UIAxes, 'Smoothed Image');
    app.UIAxes.Title.Color = [0.35 0.45 0.40];
    app.UIAxes.Title.FontSize = 14;
    app.UIAxes.Title.FontWeight = 'bold';

    % Proceed to labeling
    app.Step4LabelcomponentsButton.Enable = 'on';
        end

        % Button pushed function: Step4LabelcomponentsButton
        function Step4LabelcomponentsButtonPushed(app, event)
    if isempty(app.SmoothedImage)
        uialert(app.UIFigure, 'Please smooth the image first.', 'Missing Step');
        return;
    end

    [L, num] = bwlabel(app.SmoothedImage, 8);
    app.ConnectedComponents = L;
    app.NumComponents = num;

    app.allComponents = cell(1, num);
    
    for t = 1:num
        mask = (L == t);
        app.allComponents{t} = mask;
    end

    % Update Spinner Limits
    app.ShowAllLabelsSpinner.Limits = [1 num];
    app.ShowAllLabelsSpinner.Step = 1;
    app.ShowAllLabelsSpinner.Value = 1;

    imshow(app.SmoothedImage, 'Parent', app.UIAxes);
    title(app.UIAxes, sprintf('Labeled %d Components', num));
    app.UIAxes.Title.Color = [0.35 0.45 0.40];
    app.UIAxes.Title.FontSize = 14;
    app.UIAxes.Title.FontWeight = 'bold';
        end

        % Value changed function: ShowAllLabelsSpinner
        function ShowAllLabelsSpinnerValueChanged(app, event)
    idx = app.ShowAllLabelsSpinner.Value;
    if isempty(app.allComponents) || idx > length(app.allComponents)
        uialert(app.UIFigure, 'No component found for this index.', 'Index Error');
        return;
    end

    mask = app.allComponents{idx};

    % Apply mask on the original image
    maskedImage = app.OriginalImage;
    for channel = 1:size(maskedImage,3)
        tempChannel = maskedImage(:,:,channel);
        tempChannel(~mask) = 0; % Black out background
        maskedImage(:,:,channel) = tempChannel;
    end

    imshow(maskedImage, 'Parent', app.UIAxes);
    title(app.UIAxes, sprintf('Component %d of %d', idx, app.NumComponents));
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            % Clear stored image data
    app.OriginalImage = [];
    app.MedianFilteredImage = [];
    app.EdgeImage = [];
    app.SmoothedImage = [];
    % Reset image view
    cla(app.UIAxes); % clear axes
    title(app.UIAxes, 'Image Viewer');

    % Reset spinner
    app.ShowAllLabelsSpinner.Value = 1;
    app.ShowAllLabelsSpinner.Limits = [0 1];
    app.ShowAllLabelsSpinner.Step = 1;

    % Disable all processing buttons
    app.Step1ApplyingMedianFilterButton.Enable = 'off';
    app.Step2EdgeDetectionButton.Enable = 'off';
    app.Step3SmoothingButton.Enable = 'off';
    app.Step4LabelcomponentsButton.Enable = 'off';

    % Optional: Reset button colors if you've changed them
    buttons = [app.Step1ApplyingMedianFilterButton, app.Step2EdgeDetectionButton, ...
               app.Step3SmoothingButton, app.Step4LabelcomponentsButton, ...
               ];
    for btn = buttons
        btn.FontColor = [1 1 1]; % reset to White
    end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image viewer')
            app.UIAxes.SubtitleFontWeight = 'bold';
            app.ProcessingWorkflowPanel = uipanel(app.UIFigure);
            app.ProcessingWorkflowPanel.Title = 'Processing Workflow';
            app.Step1ApplyingMedianFilterButton = uibutton(app.ProcessingWorkflowPanel, 'push');
            app.Step1ApplyingMedianFilterButton.ButtonPushedFcn = createCallbackFcn(app, @Step1ApplyingMedianFilterButtonPushed, true);
            app.Step1ApplyingMedianFilterButton.Text = 'Step 1. Applying Median Filter';
            app.Step3SmoothingButton = uibutton(app.ProcessingWorkflowPanel, 'push');
            app.Step3SmoothingButton.ButtonPushedFcn = createCallbackFcn(app, @Step3SmoothingButtonPushed, true);
            app.Step3SmoothingButton.Text = 'Step 3. Smoothing';
            app.Step4LabelcomponentsButton = uibutton(app.ProcessingWorkflowPanel, 'push');
            app.Step4LabelcomponentsButton.ButtonPushedFcn = createCallbackFcn(app, @Step4LabelcomponentsButtonPushed, true);
            app.Step4LabelcomponentsButton.Text = 'Step 4. Label components';
            app.NONEButton = uibutton(app.ProcessingWorkflowPanel, 'push');
            app.NONEButton.Text = 'NONE';
            app.Step2EdgeDetectionButton = uibutton(app.ProcessingWorkflowPanel, 'push');
            app.Step2EdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @Step2EdgeDetectionButtonPushed, true);
            app.Step2EdgeDetectionButton.Text = 'Step 2. Edge Detection';
            app.BrowseImagesPanel = uipanel(app.UIFigure);
            app.BrowseImagesPanel.Title = 'Browse Images';
            app.PresstoLoadButton = uibutton(app.BrowseImagesPanel, 'push');
            app.PresstoLoadButton.ButtonPushedFcn = createCallbackFcn(app, @PresstoLoadButtonPushed, true);
            app.PresstoLoadButton.Text = 'Press to Load';
            app.ShowAllLabelsSpinnerLabel = uilabel(app.UIFigure);
            app.ShowAllLabelsSpinnerLabel.Text = {'Show All'; 'Labels'};
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Show All'; 'Labeled'; 'Images'};
            app.ShowAllLabelsSpinner = uispinner(app.UIFigure);
            app.ShowAllLabelsSpinner.ValueChangedFcn = createCallbackFcn(app, @ShowAllLabelsSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end